classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Label            matlab.ui.control.Label
        InformacinLabel  matlab.ui.control.Label
        RegresarButton   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RegresarButton
        function RegresarButtonPushed(app, event)
            %Regresar puerto a Usar
            
            %Quitar ventana
            app.delete;
        end

        % Callback function
        function PuertosButtonPushed(app, event)
            puertos = serialportlist;
            app.PuertosListBox.Items = puertos;
        end

        % Callback function
        function ConectarButtonPushed(app, event)
           
           if isempty(app.BaudRateEditField.Value)
              
               app.InformacinLabel.Text = '*Falta llenar datos';
               app.InformacinLabel.FontColor = [ 1.0 0.0 0.0];
           else
            app.InformacinLabel.Text = '  ';
            s = serialport(app.PuertosListBox.Value,str2double(app.BaudRateEditField.Value));
            
           end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RegresarButton = uibutton(app.UIFigure, 'push');
            app.RegresarButton.ButtonPushedFcn = createCallbackFcn(app, @RegresarButtonPushed, true);
            app.RegresarButton.Text = 'Regresar';
            app.InformacinLabel = uilabel(app.UIFigure);
            app.InformacinLabel.Text = 'Información';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Proyecto hecho por Renato Mendizabal y Estephan Portales para la clase de procesamiento de señales.'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end