classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ExportarSonidoButton         matlab.ui.control.Button
        ReproducirSonidoButton       matlab.ui.control.Button
        Switch_4                     matlab.ui.control.Switch
        AplicarFrecButton            matlab.ui.control.Button
        FrecuEditField               matlab.ui.control.EditField
        GuardarButton_2              matlab.ui.control.Button
        FsLabel                      matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        b5EditField                  matlab.ui.control.EditField
        b5EditFieldLabel             matlab.ui.control.Label
        b4EditField                  matlab.ui.control.EditField
        b4EditFieldLabel             matlab.ui.control.Label
        b3EditField                  matlab.ui.control.EditField
        b3EditFieldLabel             matlab.ui.control.Label
        b2EditField                  matlab.ui.control.EditField
        b2EditFieldLabel             matlab.ui.control.Label
        b1EditField                  matlab.ui.control.EditField
        b1EditFieldLabel             matlab.ui.control.Label
        b0EditField                  matlab.ui.control.EditField
        b0EditFieldLabel             matlab.ui.control.Label
        bListBox                     matlab.ui.control.ListBox
        bListBoxLabel                matlab.ui.control.Label
        CoeficientesactualesLabel    matlab.ui.control.Label
        aListBox                     matlab.ui.control.ListBox
        aListBoxLabel                matlab.ui.control.Label
        a5EditField                  matlab.ui.control.EditField
        a5EditFieldLabel             matlab.ui.control.Label
        a4EditField_2                matlab.ui.control.EditField
        a4EditField_2Label           matlab.ui.control.Label
        a3EditField_2                matlab.ui.control.EditField
        a3EditField_2Label           matlab.ui.control.Label
        a2EditField                  matlab.ui.control.EditField
        a2EditFieldLabel             matlab.ui.control.Label
        a1EditField_2                matlab.ui.control.EditField
        a1EditField_2Label           matlab.ui.control.Label
        a0EditField                  matlab.ui.control.EditField
        a0EditFieldLabel             matlab.ui.control.Label
        CoeficientesparaTivaCLabel   matlab.ui.control.Label
        a4EditField                  matlab.ui.control.EditField
        a4EditFieldLabel             matlab.ui.control.Label
        f4EditField                  matlab.ui.control.EditField
        f4EditFieldLabel             matlab.ui.control.Label
        a3EditField                  matlab.ui.control.EditField
        a3EditFieldLabel             matlab.ui.control.Label
        f2EditField                  matlab.ui.control.EditField
        f2EditFieldLabel             matlab.ui.control.Label
        a1EditField                  matlab.ui.control.EditField
        a1EditFieldLabel             matlab.ui.control.Label
        f1EditField                  matlab.ui.control.EditField
        f1EditFieldLabel             matlab.ui.control.Label
        wEditField                   matlab.ui.control.EditField
        wEditFieldLabel              matlab.ui.control.Label
        aEditField                   matlab.ui.control.EditField
        aEditFieldLabel              matlab.ui.control.Label
        fEditField                   matlab.ui.control.EditField
        fEditFieldLabel              matlab.ui.control.Label
        TipodefiltroFIRListBox       matlab.ui.control.ListBox
        TipodefiltroFIRListBoxLabel  matlab.ui.control.Label
        Fs2EditField                 matlab.ui.control.EditField
        Fs2EditFieldLabel            matlab.ui.control.Label
        TipodefiltroIIRListBox       matlab.ui.control.ListBox
        TipodefiltroIIRListBoxLabel  matlab.ui.control.Label
        RsEditField_2                matlab.ui.control.EditField
        RsEditField_2Label           matlab.ui.control.Label
        Fs1EditField                 matlab.ui.control.EditField
        Fs1EditFieldLabel            matlab.ui.control.Label
        RpEditField                  matlab.ui.control.EditField
        RpEditFieldLabel             matlab.ui.control.Label
        nEditField                   matlab.ui.control.EditField
        nEditFieldLabel              matlab.ui.control.Label
        FIRKnob                      matlab.ui.control.DiscreteKnob
        FIRKnobLabel                 matlab.ui.control.Label
        Switch_3                     matlab.ui.control.Switch
        IIRKnob                      matlab.ui.control.DiscreteKnob
        IIRKnobLabel                 matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        EstablecerComunicacinconTIVALabel  matlab.ui.control.Label
        PuertosListBox               matlab.ui.control.ListBox
        PuertosListBoxLabel          matlab.ui.control.Label
        BaudRateEditField            matlab.ui.control.EditField
        BaudRateEditFieldLabel       matlab.ui.control.Label
        ConectarButton               matlab.ui.control.Button
        PuertosButton                matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        ReloadButton                 matlab.ui.control.Button
        Switch_2                     matlab.ui.control.Switch
        InformacinButton             matlab.ui.control.Button
        IntensidadEfecSliderLabel    matlab.ui.control.Label
        IntensidadEfecSlider         matlab.ui.control.Slider
        CantidaddeMuestrasEditFieldLabel  matlab.ui.control.Label
        CantidaddeMuestrasEditField  matlab.ui.control.EditField
        EfectosLabel                 matlab.ui.control.Label
        Lamp                         matlab.ui.control.Lamp
        DatosamostrarLabel_2         matlab.ui.control.Label
        GrabarButton_2               matlab.ui.control.Button
        GuardarButton                matlab.ui.control.Button
        dBCheckBox_2                 matlab.ui.control.CheckBox
        SealenBrutoCheckBox          matlab.ui.control.CheckBox
        SealModificadaCheckBox       matlab.ui.control.CheckBox
        GrabacionesLabel             matlab.ui.control.Label
        ListBox                      matlab.ui.control.ListBox
        EfectosListBox               matlab.ui.control.ListBox
        ExportarButton               matlab.ui.control.Button
        CargarSealButton             matlab.ui.control.Button
        AplicarButton                matlab.ui.control.Button
        CargarFiltroButton           matlab.ui.control.Button
        SalirButton                  matlab.ui.control.Button
        DisearFiltroButton           matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
    end


    properties (Access = public)% Variables globales
        originalsignal; % Description
        outsignal;
        Fs;
        Fs1 = 1000;
        capturesignal;
        BaudRate;
        serialdata;
        COMPort;
        tempo;
        filtro;
        file;
        path;
        coeficientesaactuales;
        coeficientesbactuales;


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.originalsignal; %Declarar variables al inicar app
            app.outsignal;
            app.BaudRate;
            app.serialdata;
            app.COMPort;
            app.Fs;
            app.capturesignal;
            app.Fs1;
            app.tempo;
            app.filtro;
            app.file;
            app.path;
            app.coeficientesaactuales;
            app.coeficientesbactuales;

        end

        % Callback function
        function SealenbrutoCheckBoxValueChanged(app, event)
            value = app.SealenbrutoCheckBox.Value;% Seleccion db o no db

            if value
                ylabel(app.UIAxes, 'dB');
            else
                ylabel(app.UIAxes, 'No dB');


            end
        end

        % Value changed function: dBCheckBox_2
        function dBCheckBox_2ValueChanged(app, event)
            value = app.dBCheckBox_2.Value;


            % if app.dBCheckBox_2.Value
            %    ylabel(app.UIAxes_2, 'dB');
            % else
            %   ylabel(app.UIAxes_2, 'No dB');


            %   end

        end

        % Value changed function: SealenBrutoCheckBox
        function SealenBrutoCheckBoxValueChanged(app, event)
            value = app.SealenBrutoCheckBox.Value;% Visualizar o no la senal

            if value
                app.UIAxes.Visible = 'on';
            else
                app.UIAxes.Visible = 'off';
            end

        end

        % Callback function
        function dBCheckBox_3ValueChanged(app, event)
            value = app.dBCheckBox_3.Value;% Ver db o no db

            if value
                ylabel(app.UIAxes, 'dB')
            else
                ylabel(app.UIAxes, 'No dB')


            end

        end

        % Value changed function: SealModificadaCheckBox
        function SealModificadaCheckBoxValueChanged(app, event)
            value = app.SealModificadaCheckBox.Value;%Visualizar señal o no
            if value
                app.UIAxes_2.Visible = 'on';
            else
                app.UIAxes_2.Visible = 'off';
            end
        end

        % Callback function
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;% 
            if value == "Filtro"

                app.FiltrosListBox.Visible = 'on';
                app.EfectosListBox.Visible = 'off';


            elseif value == "Efecto"

                app.FiltrosListBox.Visible = 'off';
                app.EfectosListBox.Visible = 'on';


            end


        end

        % Button pushed function: CargarSealButton
        function CargarSealButtonPushed(app, event)

            %[file,path,indx] = uigetfile


            %filename = [path,file]

            %if isequal(file,0)
            %  disp('User selected Cancel');
            %else
            % disp(['User selected ', fullfile(path, file),...
            % ' and filter index: ', num2str(indx)]);
            nombre = zeros(length(app.file),1);
            direct = zeros(length(app.path),1);

            nombre = app.file;
            direct = app.path;

            index =  find(nombre == string(app.ListBox.Value));

            %string(direct(index))
            filename = convertStringsToChars(append(string(direct(index)),'\',string(nombre(index))));% obtenemos direccion y nombre de archivo y juntamos ambos 

            %nombre(index)

            %filename = [convertStringsToChars(append(string(direct(index)),'\')),nombre(index)]
            %filename = convertStringsToChars(filename)
            s = load(filename);% obtenemos datos que queremos del .mat de la señal
            app.originalsignal = s.x;
            app.Fs = s.Fs;
            plot(app.UIAxes_2, s.x);


            app.Label_3.Text = string(s.Fs);

            %end


        end

        % Button pushed function: SalirButton
        function SalirButtonPushed(app, event)
            app.delete;% cerrrar programa
        end

        % Callback function
        function EstablecerComunicacinButtonPushed(app, event)
            app2; %abrir informacion

        end

        % Button pushed function: AplicarButton
        function AplicarButtonPushed(app, event)

            % aquí se aplicarían filtros


            x = app.originalsignal; %% Se aplican todos los filtros y efectos según lo que se quiera aplicar
            Fs = app.Fs;
            y = 0;
            b = 0;
            a = 0;


            % Se seleccionan filtros

            if app.Switch_3.Value == "IIR"

                if app.IIRKnob.Value == "Butterworth"

                    if app.TipodefiltroIIRListBox.Value == "low"
                        temporal = zeros(2,1);

                        T1 = str2double(app.Fs1EditField.Value);
                        T2 = str2double(app.Fs2EditField.Value);


                        [b,a] = butter(str2double(app.nEditField.Value),(2*pi/Fs)*T1,app.TipodefiltroIIRListBox.Value);
                        y = filter(b,a,x);


                    elseif app.TipodefiltroIIRListBox.Value == "high" % se aplica según si se quiere pasabaja, pasaalta, etc.
                        temporal = zeros(2,1);

                        T1 = str2double(app.Fs1EditField.Value);
                        T2 = str2double(app.Fs2EditField.Value);


                        [b,a] = butter(str2double(app.nEditField.Value),(2*pi/Fs)*T1,app.TipodefiltroIIRListBox.Value);
                        y = filter(b,a,x);

                    elseif app.TipodefiltroIIRListBox.Value == "bandpass"

                        temporal = zeros(2,1);

                        T1 = str2double(app.Fs1EditField.Value);
                        T2 = str2double(app.Fs2EditField.Value);


                        temporal(1) = string((2*pi/Fs)*T1);
                        temporal(2) = string((2*pi/Fs)*T2);


                        [b,a] = butter(str2double(app.nEditField.Value),temporal,app.TipodefiltroIIRListBox.Value);
                        y = filter(b,a,x);


                    elseif app.TipodefiltroIIRListBox.Value == "stop"

                        temporal = zeros(2,1);

                        T1 = str2double(app.Fs1EditField.Value);
                        T2 = str2double(app.Fs2EditField.Value);


                        temporal(1) = (2*pi/Fs)*T1;
                        temporal(2) = (2*pi/Fs)*T2;

                        [b,a] = butter(str2double(app.nEditField.Value),temporal,app.TipodefiltroIIRListBox.Value);
                        y = filter(b,a,x);


                    end


                elseif app.IIRKnob.Value == "Chebyshev tipo 1"

                    if app.TipodefiltroIIRListBox.Value == "low"
                        temporal = zeros(2,1);

                        T1 = str2double(app.Fs1EditField.Value);
                        T2 = str2double(app.Fs2EditField.Value);


                        [b,a] = cheby1(str2double(app.nEditField.Value),str2double(app.RpEditField.Value),(2*pi/Fs)*T1,app.TipodefiltroIIRListBox.Value);
                        y = filter(b,a,x);

                    elseif app.TipodefiltroIIRListBox.Value == "high"
                        temporal = zeros(2,1);

                        T1 = str2double(app.Fs1EditField.Value);
                        T2 = str2double(app.Fs2EditField.Value);


                        [b,a] = cheby1(str2double(app.nEditField.Value),str2double(app.RpEditField.Value),(2*pi/Fs)*T1,app.TipodefiltroIIRListBox.Value);
                        y = filter(b,a,x);

                    elseif app.TipodefiltroIIRListBox.Value == "bandpass"

                        temporal = zeros(2,1);

                        T1 = str2double(app.Fs1EditField.Value);
                        T2 = str2double(app.Fs2EditField.Value);


                        temporal(1) = string((2*pi/Fs)*T1);
                        temporal(2) = string((2*pi/Fs)*T2);


                        [b,a] = cheby1(str2double(app.nEditField.Value),str2double(app.RpEditField.Value),temporal,app.TipodefiltroIIRListBox.Value);
                        y = filter(b,a,x);


                    elseif app.TipodefiltroIIRListBox.Value == "stop"

                        temporal = zeros(2,1);

                        T1 = str2double(app.Fs1EditField.Value);
                        T2 = str2double(app.Fs2EditField.Value);


                        temporal(1) = (2*pi/Fs)*T1;
                        temporal(2) = (2*pi/Fs)*T2;
                        [b,a] = cheby1(str2double(app.nEditField.Value),str2double(app.RpEditField.Value),temporal,app.TipodefiltroIIRListBox.Value);
                        y = filter(b,a,x);


                    end

                elseif app.IIRKnob.Value == "Chebyshev tipo 2"

                    if app.TipodefiltroIIRListBox.Value == "low"
                        temporal = zeros(2,1);

                        T1 = str2double(app.Fs1EditField.Value);
                        T2 = str2double(app.Fs2EditField.Value);


                        [b,a] = cheby2(str2double(app.nEditField.Value),str2double(app.RsEditField_2.Value),(2*pi/Fs)*T1,app.TipodefiltroIIRListBox.Value);
                        y = filter(b,a,x);

                    elseif app.TipodefiltroIIRListBox.Value == "high"
                        temporal = zeros(2,1);

                        T1 = str2double(app.Fs1EditField.Value);
                        T2 = str2double(app.Fs2EditField.Value);


                        [b,a] = cheby2(str2double(app.nEditField.Value),str2double(app.RsEditField_2.Value),(2*pi/Fs)*T1,app.TipodefiltroIIRListBox.Value);
                        y = filter(b,a,x);

                    elseif app.TipodefiltroIIRListBox.Value == "bandpass"

                        temporal = zeros(2,1);

                        T1 = str2double(app.Fs1EditField.Value);
                        T2 = str2double(app.Fs2EditField.Value);


                        temporal(1) = string((2*pi/Fs)*T1);
                        temporal(2) = string((2*pi/Fs)*T2);


                        [b,a] = cheby2(str2double(app.nEditField.Value),str2double(app.RsEditField_2.Value),temporal,app.TipodefiltroIIRListBox.Value);
                        y = filter(b,a,x);


                    elseif app.TipodefiltroIIRListBox.Value == "stop"

                        temporal = zeros(2,1);

                        T1 = str2double(app.Fs1EditField.Value);
                        T2 = str2double(app.Fs2EditField.Value);


                        temporal(1) = (2*pi/app.Fs)*T1;
                        temporal(2) = (2*pi/Fs)*T2;
                        [b,a] = cheby2(str2double(app.nEditField.Value),str2double(app.RsEditField_2.Value),temporal,app.TipodefiltroIIRListBox.Value);
                        y = filter(b,a,x);


                    end

                elseif app.IIRKnob.Value == "Elíptico"

                    if app.TipodefiltroIIRListBox.Value == "low"
                        temporal = zeros(2,1);

                        T1 = str2double(app.Fs1EditField.Value);
                        T2 = str2double(app.Fs2EditField.Value);


                        [b,a] = ellip(str2double(app.nEditField.Value),str2double(app.RpEditField.Value) ,str2double(app.RsEditField_2.Value),(2*pi/Fs)*T1,app.TipodefiltroIIRListBox.Value);
                        y = filter(b,a,x);

                    elseif app.TipodefiltroIIRListBox.Value == "high"
                        temporal = zeros(2,1);

                        T1 = str2double(app.Fs1EditField.Value);
                        T2 = str2double(app.Fs2EditField.Value);


                        [b,a] = ellip(str2double(app.nEditField.Value),str2double(app.RpEditField.Value) ,str2double(app.RsEditField_2.Value),(2*pi/Fs)*T1,app.TipodefiltroIIRListBox.Value);
                        y = filter(b,a,x);

                    elseif app.TipodefiltroIIRListBox.Value == "bandpass"

                        temporal = zeros(2,1);

                        T1 = str2double(app.Fs1EditField.Value);
                        T2 = str2double(app.Fs2EditField.Value);


                        temporal(1) = string((2*pi/Fs)*T1);
                        temporal(2) = string((2*pi/Fs)*T2);


                        [b,a] = ellip(str2double(app.nEditField.Value),str2double(app.RpEditField.Value) ,str2double(app.RsEditField_2.Value),temporal,app.TipodefiltroIIRListBox.Value);
                        y = filter(b,a,x);


                    elseif app.TipodefiltroIIRListBox.Value == "stop"

                        temporal = zeros(2,1);

                        T1 = str2double(app.Fs1EditField.Value);
                        T2 = str2double(app.Fs2EditField.Value);


                        temporal(1) = (2*pi/Fs)*T1;
                        temporal(2) = (2*pi/Fs)*T2;
                        [b,a] = ellip(str2double(app.nEditField.Value),str2double(app.RpEditField.Value) ,str2double(app.RsEditField_2.Value),temporal,app.TipodefiltroIIRListBox.Value);
                        y = filter(b,a,x);


                    end

                elseif app.IIRKnob.Value == "None"
                    y = x;

                end

            elseif app.Switch_3.Value == "FIR" % Se aplican flitros FIR

                if app.FIRKnob.Value == "None"
                    y= x;
                elseif app.FIRKnob.Value == "Equiripple"

                elseif app.FIRKnob.Value == "Least-squares"

                    if app.TipodefiltroFIRListBox.Value == "hilbert"

                        temporalf = zeros(4,1);
                        temporala = zeros(4,1);

                        f = str2double(app.fEditField.Value);
                        f1 = str2double(app.f1EditField.Value);
                        f2 = str2double(app.f2EditField.Value);
                        f3 = str2double(app.f4EditField.Value);

                        temporalf(1) = f;
                        temporalf(2) = f1;
                        temporalf(3) = f2;
                        temporalf(4) = f3;

                        a = str2double(app.aEditField.Value);
                        a1 = str2double(app.a1EditField.Value);
                        a2 = str2double(app.a3EditField.Value);
                        a3 = str2double(app.a4EditField.Value);

                        temporala(1) = a;
                        temporala(2) = a1;
                        temporala(3) = a2;
                        temporala(4) = a3;


                        b = firls(str2double(app.nEditField.Value),temporalf,temporala,app.TipodefiltroFIRListBox.Value);
                        a=0;
                        y = filter(b,1,x);


                    elseif app.TipodefiltroFIRListBox.Value == "differentiator"

                        temporalf = zeros(4,1);
                        temporala = zeros(4,1);

                        f = str2double(app.fEditField.Value);
                        f1 = str2double(app.f1EditField.Value);
                        f2 = str2double(app.f2EditField.Value);
                        f3 = str2double(app.f4EditField.Value);

                        temporalf(1) = f;
                        temporalf(2) = f1;
                        temporalf(3) = f2;
                        temporalf(4) = f3;

                        a = str2double(app.aEditField.Value);
                        a1 = str2double(app.a1EditField.Value);
                        a2 = str2double(app.a3EditField.Value);
                        a3 = str2double(app.a4EditField.Value);

                        temporala(1) = a;
                        temporala(2) = a1;
                        temporala(3) = a2;
                        temporala(4) = a3;


                        b = firls(str2double(app.nEditField.Value),temporalf,temporala,app.TipodefiltroFIRListBox.Value);
                        a=0;
                        y = filter(b,1,x);


                    end


                elseif app.FIRKnob.Value == "Constr. Least-Squares"

                elseif app.FIRKnob.Value == "Generalizes Equiripple"


                end

            end


            %Efectos de sonido

            if app.EfectosListBox.Value == "None" % Se aplican efectos

                y = y;

            elseif app.EfectosListBox.Value == "Fade in"

                len = length(y);
                fadein = linspace(0,1,len);

                for i = 1:len
                    y(i) = y(i) * fadein(i);
                end

            elseif app.EfectosListBox.Value == "Fade out"

                len = length(y);
                fadein = linspace(1,0,len);

                for i = 1:len
                    y(i) = y(i) * fadein(i);
                end

            elseif app.EfectosListBox.Value == "Distorsión"
                noise = randn(size(y));

                dispol = [0.5 0.75 1 0];
                y = polyval(dispol,y+noise);


            elseif app.EfectosListBox.Value == "Eco"
                b = [1,zeros(1,0.5*Fs),0.65];

                y = filter(b,1,y);

            end


            %Se escoge si se quiere en tiempo o frecuencia

            if app.Switch_2.Value == "Tiempo" %% Dominio del tiempo
                plot(app.UIAxes_2,y);


            elseif app.Switch_2.Value == "Frecu." % dominio de la frecuencia, ver espectro de amplitud unilateral

                T = 1/Fs;
                L = length(y);
                t = (0:L-1)*T;

                Y = fft(y);
                P2 = abs(Y/L);
                P1 = P2(1:L/2+1);
                P1(2:end-1) = 2*P1(2:end-1);
                f = Fs*(0:(L/2))/L;


                if app.dBCheckBox_2.Value
                    ylabel(app.UIAxes_2, 'dB');% Ver en db o no
                    P1 = mag2db(P1);

                else
                    ylabel(app.UIAxes_2, 'No dB');
                    P1 = P1;

                end

                plot(app.UIAxes_2,f,P1);
                 xlim([0 500]);

            end

            %extra = y
            %app.outsignal = zeros(length(app.originalsignal),1);


            app.coeficientesaactuales = a; % Guardar algunos valores en variables globales
            app.coeficientesbactuales = b;

            app.aListBox.Items = string(a);
            app.bListBox.Items = string(b);


            app.outsignal = y;

        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
        end

        % Button pushed function: ReloadButton
        function ReloadButtonPushed(app, event)
            file = dir(uigetdir);% Obtener contenido de carpeta para cargar señales
            names = {file.name};
            folders = {file.folder};
            app.ListBox.Items = names;
            app.file = names;
            app.path = folders;


        end

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)

        end

        % Button pushed function: GrabarButton_2
        function GrabarButton_2Pushed(app, event)

            if app.Switch_4.Value == "Muestra" % Si el espacio está vacío se indica, si no se toman las muestras
                if isempty(app.CantidaddeMuestrasEditField.Value)
                    app.Label.Text = '*Ingresar muestras';
                    app.Label.FontColor = [ 1.0 0.0 0.0];

                else
                    cla(app.UIAxes);
                    app.Lamp.Color = [ 0.00,1.00,0.00];
                    app.Label.Text = '  ';
                    recoleccion = zeros(str2double(app.CantidaddeMuestrasEditField.Value),1);
                    flush(app.serialdata);

                    for i = 1:str2double(app.CantidaddeMuestrasEditField.Value)% Se tomas las muestras deseadas


                        dato = readline(app.serialdata);
                        dato = str2double(dato);


                        %if dato > 0;
                        recoleccion(i,1) = dato;
                        % plot(app.UIAxes,recoleccion);

                        %end

                    end

                    app.Lamp.Color = [1.00,0.00,0.00];
                    app.capturesignal = recoleccion;
                    plot(app.UIAxes,app.capturesignal);

                end

            elseif app.Switch_4.Value == "Continuo" % Si se quiere ver en "Tiempo real"

                while app.Switch_4.Value == "Continuo"
                    if isempty(app.CantidaddeMuestrasEditField.Value)
                        app.Label.Text = '*Ingresar muestras';
                        app.Label.FontColor = [ 1.0 0.0 0.0];

                    else
                        %cla(app.UIAxes);
                        app.Lamp.Color = [ 0.00,1.00,0.00];
                        app.Label.Text = '  ';
                        recoleccion = zeros(str2double(app.CantidaddeMuestrasEditField.Value),1);
                        %flush(app.serialdata);

                        for i = 1:str2double(app.CantidaddeMuestrasEditField.Value)


                            dato = readline(app.serialdata);
                            dato = str2double(dato);


                            %if dato > 0;
                            recoleccion(i,1) = dato;
                            % plot(app.UIAxes,recoleccion);

                            %end

                        end

                        app.Lamp.Color = [1.00,0.00,0.00];
                        app.capturesignal = recoleccion;
                        plot(app.UIAxes,app.capturesignal);

                    end

                end

            end

        end

        % Button pushed function: PuertosButton
        function PuertosButtonPushed(app, event)
            puertos = serialportlist; % Se obtienen los puertos disponibles, se pueden seleccionar
            app.PuertosListBox.Items = puertos;
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            if isempty(app.BaudRateEditField.Value)%% Si no se ha puesto el baudrate se dice y si si, se conecta al dispositivo de captura

                app.Label_2.Text = '*Falta llenar datos';
                app.Label_2.FontColor = [ 1.0 0.0 0.0];
            else
                app.Label_2.Text = '  ';
                s = serialport(app.PuertosListBox.Value,str2double(app.BaudRateEditField.Value));
                app.serialdata = s;
            end
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            x = app.capturesignal;%% Para guardar señal capturada
            %app.Fs1 = 1000;
            Fs = app.Fs1;

            [file,path] = uiputfile('*.mat');
            if isequal(file,0) || isequal(path,0)
                disp('User clicked Cancel.')
            else
                disp(['User selected ',fullfile(path,file),...
                    ' and then clicked Save.'])
            end


            newfilename = fullfile(path, file);
            save(newfilename, 'x','Fs')
        end

        % Button pushed function: ExportarButton
        function ExportarButtonPushed(app, event)
            original = string(app.originalsignal); % se guarda en csv señales
            Fs = app.Fs1;
            mod = app.outsignal;


            a = uigetdir;
            b = uiputfile('*.csv');

            file = string(b);
            path = string(a);

            path = append(a,'\');

            guardar = append(path,file);

            matrix = zeros(length(original),2);


            for i = 1:length(original)
                matrix(i,1) = original(i);
            end

            for i = 1:length(mod)
                matrix(i,2) = mod(i);
            end


            csvwrite(guardar,matrix);


        end

        % Value changed function: FIRKnob
        function FIRKnobValueChanged(app, event)
            value = app.FIRKnob.Value; % Según filtro que se quiere se habilitan espacios para introducir datos


            if app.Switch_3.Value == "FIR"

                if value == "Equiripple"


                elseif value == "None"
                    app.nEditField.Visible = 'off';
                    app.Fs1EditField.Visible = 'off';
                    app.Fs2EditField.Visible = 'off';
                    app.RpEditField.Visible = 'off';
                    app.RsEditField_2.Visible = 'off';
                    app.fEditField.Visible = 'off';
                    app.aEditField.Visible = 'off';
                    app.wEditField.Visible = 'off';


                elseif value == "Least-squares"
                    app.nEditField.Visible = 'on';
                    app.Fs1EditField.Visible = 'off';
                    app.Fs2EditField.Visible = 'off';
                    app.RpEditField.Visible = 'off';
                    app.RsEditField_2.Visible = 'off';
                    app.fEditField.Visible = 'on';
                    app.aEditField.Visible = 'on';
                    app.wEditField.Visible = 'on';
                    app.f1EditField.Visible = 'on';
                    app.a1EditField.Visible = 'on';
                    app.f4EditField.Visible = 'on';
                    app.f2EditField.Visible = 'on';
                    app.a3EditField.Visible = 'on';
                    app.a4EditField.Visible = 'on';

                elseif value == "Constr. Least-Squares"


                elseif value == "Generalizes Equiripple"


                end


            end


        end

        % Value changed function: IIRKnob
        function IIRKnobValueChanged(app, event)
            value = app.IIRKnob.Value;% Según filtro que se quiere se habilitan espacios para introducir datos

            if app.Switch_3.Value == "IIR"
                if value == "Butterworth"

                    app.nEditField.Visible = 'on';
                    app.Fs1EditField.Visible = 'on';
                    app.Fs2EditField.Visible = 'on';
                    app.RpEditField.Visible = 'off';
                    app.RsEditField_2.Visible = 'off';
                    app.fEditField.Visible = 'off';
                    app.aEditField.Visible = 'off';
                    app.wEditField.Visible = 'off';
                    app.f1EditField.Visible = 'off';
                    app.a1EditField.Visible = 'off';
                    app.f4EditField.Visible = 'off';
                    app.f2EditField.Visible = 'off';
                    app.a3EditField.Visible = 'off';
                    app.a4EditField.Visible = 'off';


                elseif value == "None"
                    app.nEditField.Visible = 'off';
                    app.Fs1EditField.Visible = 'off';
                    app.Fs2EditField.Visible = 'off';
                    app.RpEditField.Visible = 'off';
                    app.RsEditField_2.Visible = 'off';
                    app.fEditField.Visible = 'off';
                    app.aEditField.Visible = 'off';
                    app.wEditField.Visible = 'off';
                    app.f1EditField.Visible = 'off';
                    app.a1EditField.Visible = 'off';
                    app.f4EditField.Visible = 'off';
                    app.f2EditField.Visible = 'off';
                    app.a3EditField.Visible = 'off';
                    app.a4EditField.Visible = 'off';

                elseif value == "Chebyshev tipo 1"

                    app.nEditField.Visible = 'on';
                    app.Fs1EditField.Visible = 'on';
                    app.Fs2EditField.Visible = 'on';
                    app.RpEditField.Visible = 'on';
                    app.RsEditField_2.Visible = 'off';
                    app.fEditField.Visible = 'off';
                    app.aEditField.Visible = 'off';
                    app.wEditField.Visible = 'off';
                    app.f1EditField.Visible = 'off';
                    app.a1EditField.Visible = 'off';
                    app.f4EditField.Visible = 'off';
                    app.f2EditField.Visible = 'off';
                    app.a3EditField.Visible = 'off';
                    app.a4EditField.Visible = 'off';


                elseif value == "Chebyshev tipo 2"

                    app.nEditField.Visible = 'on';
                    app.Fs1EditField.Visible = 'on';
                    app.Fs2EditField.Visible = 'on';
                    app.RpEditField.Visible = 'off';
                    app.RsEditField_2.Visible = 'on';
                    app.fEditField.Visible = 'off';
                    app.aEditField.Visible = 'off';
                    app.wEditField.Visible = 'off';
                    app.f1EditField.Visible = 'off';
                    app.a1EditField.Visible = 'off';
                    app.f4EditField.Visible = 'off';
                    app.f2EditField.Visible = 'off';
                    app.a3EditField.Visible = 'off';
                    app.a4EditField.Visible = 'off';


                elseif value == "Elíptico"

                    app.nEditField.Visible = 'on';
                    app.Fs1EditField.Visible = 'on';
                    app.Fs2EditField.Visible = 'on';
                    app.RpEditField.Visible = 'on';
                    app.RsEditField_2.Visible = 'on';
                    app.fEditField.Visible = 'off';
                    app.aEditField.Visible = 'off';
                    app.wEditField.Visible = 'off';
                    app.f1EditField.Visible = 'off';
                    app.a1EditField.Visible = 'off';
                    app.f4EditField.Visible = 'off';
                    app.f2EditField.Visible = 'off';
                    app.a3EditField.Visible = 'off';
                    app.a4EditField.Visible = 'off';

                end
            end

        end

        % Button pushed function: DisearFiltroButton
        function DisearFiltroButtonPushed(app, event)
            filterDesigner;% Se abre filter designer
        end

        % Callback function
        function ButtonPushed(app, event)
% Funcion sin uso, fue de prueba
            writeline(app.serialdata,'A'); % 
            writeline(app.serialdata,'A');
            writeline(app.serialdata,'A');

            dato = readline(app.serialdata);
            dato = str2double(dato);
            app.FrecuEditField.Value = string(dato);


        end

        % Callback function
        function Button2Pushed(app, event)
% Funcion sin uso, fue de prueba
            writeline(app.serialdata,'B');

            dato = readline(app.serialdata);
            dato = str2double(dato);
            app.FrecuEditField.Value = string(dato);
        end

        % Button pushed function: CargarFiltroButton
        function CargarFiltroButtonPushed(app, event)
            if isempty(app.a0EditField.Value) % Si el espacio está vacío de ponen ceros
                app.a0EditField.Value = '0';
            end
            if isempty(app.a1EditField_2.Value)
                app.a1EditField_2.Value = '0';
            end
            if isempty(app.a2EditField.Value)
                app.a2EditField.Value = '0';
            end
            if isempty(app.a3EditField_2.Value)
                app.a3EditField_2.Value = '0';
            end
            if isempty(app.a4EditField_2.Value)
                app.a4EditField_2.Value = '0';
            end
            if isempty(app.a5EditField.Value)
                app.a5EditField.Value = '0';
            end
            if isempty(app.b0EditField.Value)
                app.b0EditField.Value = '0';
            end
            if isempty(app.b1EditField.Value)
                app.b1EditField.Value = '0';
            end
            if isempty(app.b2EditField.Value)
                app.b2EditField.Value = '0';
            end
            if isempty(app.b3EditField.Value)
                app.b3EditField.Value = '0';
            end
            if isempty(app.b4EditField.Value)
                app.b4EditField.Value = '0';
            end
            if isempty(app.b5EditField.Value)
                app.b5EditField.Value = '0';
            end

            writeline(app.serialdata,'A'); % Se mandan coeficientes a la tiva
            writeline(app.serialdata,app.a0EditField.Value);
            writeline(app.serialdata,',');
            writeline(app.serialdata,app.a1EditField_2.Value);
            writeline(app.serialdata,',');
            writeline(app.serialdata,app.a2EditField.Value);
            writeline(app.serialdata,',');
            writeline(app.serialdata,app.a3EditField_2.Value);
            writeline(app.serialdata,',');
            writeline(app.serialdata,app.a4EditField_2.Value);
            writeline(app.serialdata,',');
            writeline(app.serialdata,app.a5EditField.Value);
            writeline(app.serialdata,',');
            writeline(app.serialdata,app.b0EditField.Value);
            writeline(app.serialdata,',');
            writeline(app.serialdata,app.b1EditField.Value);
            writeline(app.serialdata,',');
            writeline(app.serialdata,app.b2EditField.Value);
            writeline(app.serialdata,',');
            writeline(app.serialdata,app.b3EditField.Value);
            writeline(app.serialdata,',');
            writeline(app.serialdata,app.b4EditField.Value);
            writeline(app.serialdata,',');
            writeline(app.serialdata,app.b5EditField.Value);
            writeline(app.serialdata,'B');


        end

        % Button pushed function: InformacinButton
        function InformacinButtonPushed(app, event)
            app2;
        end

        % Button pushed function: GuardarButton_2
        function GuardarButton_2Pushed(app, event)
            a = app.coeficientesaactuales ;% Se guardan coeficientes del filtro que se está usando
            b = app.coeficientesbactuales ;


            [file,path] = uiputfile('*.mat');
            if isequal(file,0) || isequal(path,0)
                disp('User clicked Cancel.')
            else
                disp(['User selected ',fullfile(path,file),...
                    ' and then clicked Save.'])
            end


            newfilename = fullfile(path, file);
            save(newfilename, 'a','b')
        end

        % Button pushed function: AplicarFrecButton
        function AplicarFrecButtonPushed(app, event)
            valorfrec = str2double(app.FrecuEditField.Value); % Se manda frecuencia de muestreo a la tiva (Si se quiere cambiar)
            app.Fs1 = valorfrec;

            writeline(app.serialdata,'F');
            writeline(app.serialdata,app.FrecuEditField.Value);
            writeline(app.serialdata,'H');


        end

        % Value changed function: EfectosListBox
        function EfectosListBoxValueChanged(app, event)
            value = app.EfectosListBox.Value;

        end

        % Button pushed function: ReproducirSonidoButton
        function ReproducirSonidoButtonPushed2(app, event)
            Fs = app.Fs; % Se escucha señal que se procesa
            x = app.outsignal;

            sound(x,Fs);
           
        end

        % Button pushed function: ExportarSonidoButton
        function ExportarSonidoButtonPushed(app, event)
            original = app.outsignal;
            Fs = app.Fs;% Exportar sonido si se quiere


            a = uigetdir;
            b = uiputfile('*.wav');

            file = string(b);
            path = string(a);

            path = append(a,'\');

            guardar = append(path,file);
            audiowrite(guardar,original,Fs);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Señal modificada')
            xlabel(app.UIAxes_2, 'Hz')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Datos en bruto')
            xlabel(app.UIAxes, 'Muestras')
            zlabel(app.UIAxes, 'Z')
            app.DisearFiltroButton = uibutton(app.UIFigure, 'push');
            app.DisearFiltroButton.ButtonPushedFcn = createCallbackFcn(app, @DisearFiltroButtonPushed, true);
            app.DisearFiltroButton.Text = 'Diseñar Filtro';
            app.SalirButton = uibutton(app.UIFigure, 'push');
            app.SalirButton.ButtonPushedFcn = createCallbackFcn(app, @SalirButtonPushed, true);
            app.SalirButton.Text = 'Salir';
            app.CargarFiltroButton = uibutton(app.UIFigure, 'push');
            app.CargarFiltroButton.ButtonPushedFcn = createCallbackFcn(app, @CargarFiltroButtonPushed, true);
            app.CargarFiltroButton.Text = 'Cargar Filtro';
            app.AplicarButton = uibutton(app.UIFigure, 'push');
            app.AplicarButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarButtonPushed, true);
            app.AplicarButton.Text = 'Aplicar';
            app.CargarSealButton = uibutton(app.UIFigure, 'push');
            app.CargarSealButton.ButtonPushedFcn = createCallbackFcn(app, @CargarSealButtonPushed, true);
            app.CargarSealButton.Text = 'Cargar Señal';
            app.ExportarButton = uibutton(app.UIFigure, 'push');
            app.ExportarButton.ButtonPushedFcn = createCallbackFcn(app, @ExportarButtonPushed, true);
            app.ExportarButton.Text = 'Exportar';
            app.EfectosListBox = uilistbox(app.UIFigure);
            app.EfectosListBox.Items = {'None', 'Fade in', 'Fade out', 'Distorsión', 'Eco'};
            app.EfectosListBox.ValueChangedFcn = createCallbackFcn(app, @EfectosListBoxValueChanged, true);
            app.EfectosListBox.Value = 'None';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.GrabacionesLabel = uilabel(app.UIFigure);
            app.GrabacionesLabel.Text = 'Grabaciones';
            app.SealModificadaCheckBox = uicheckbox(app.UIFigure);
            app.SealModificadaCheckBox.ValueChangedFcn = createCallbackFcn(app, @SealModificadaCheckBoxValueChanged, true);
            app.SealModificadaCheckBox.Text = 'Señal Modificada';
            app.SealModificadaCheckBox.Value = true;
            app.SealenBrutoCheckBox = uicheckbox(app.UIFigure);
            app.SealenBrutoCheckBox.ValueChangedFcn = createCallbackFcn(app, @SealenBrutoCheckBoxValueChanged, true);
            app.SealenBrutoCheckBox.Text = 'Señal en Bruto';
            app.SealenBrutoCheckBox.Value = true;
            app.dBCheckBox_2 = uicheckbox(app.UIFigure);
            app.dBCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @dBCheckBox_2ValueChanged, true);
            app.dBCheckBox_2.Text = 'dB';
            app.dBCheckBox_2.Value = true;
            app.GuardarButton = uibutton(app.UIFigure, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.GrabarButton_2 = uibutton(app.UIFigure, 'push');
            app.GrabarButton_2.ButtonPushedFcn = createCallbackFcn(app, @GrabarButton_2Pushed, true);
            app.GrabarButton_2.Text = 'Grabar';
            app.DatosamostrarLabel_2 = uilabel(app.UIFigure);
            app.DatosamostrarLabel_2.Text = 'Datos a mostrar';
            app.Lamp = uilamp(app.UIFigure);
            app.EfectosLabel = uilabel(app.UIFigure);
            app.EfectosLabel.Text = 'Efectos';
            app.CantidaddeMuestrasEditField = uieditfield(app.UIFigure, 'text');
            app.CantidaddeMuestrasEditFieldLabel = uilabel(app.UIFigure);
            app.CantidaddeMuestrasEditFieldLabel.Text = 'Cantidad de Muestras';
            app.IntensidadEfecSlider = uislider(app.UIFigure);
            app.IntensidadEfecSlider.Limits = [0 3];
            app.IntensidadEfecSliderLabel = uilabel(app.UIFigure);
            app.IntensidadEfecSliderLabel.Text = 'Intensidad Efec.';
            app.InformacinButton = uibutton(app.UIFigure, 'push');
            app.InformacinButton.ButtonPushedFcn = createCallbackFcn(app, @InformacinButtonPushed, true);
            app.InformacinButton.Text = 'Información';
            app.Switch_2 = uiswitch(app.UIFigure, 'slider');
            app.Switch_2.Items = {'Tiempo', 'Frecu.'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Tiempo';
            app.ReloadButton = uibutton(app.UIFigure, 'push');
            app.ReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadButtonPushed, true);
            app.ReloadButton.Text = 'Reload';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.PuertosButton = uibutton(app.UIFigure, 'push');
            app.PuertosButton.ButtonPushedFcn = createCallbackFcn(app, @PuertosButtonPushed, true);
            app.PuertosButton.Text = 'Puertos';
            app.ConectarButton = uibutton(app.UIFigure, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.BaudRateEditFieldLabel = uilabel(app.UIFigure);
            app.BaudRateEditFieldLabel.Text = 'Baud Rate';
            app.BaudRateEditField = uieditfield(app.UIFigure, 'text');
            app.PuertosListBoxLabel = uilabel(app.UIFigure);
            app.PuertosListBoxLabel.Text = 'Puertos';
            app.PuertosListBox = uilistbox(app.UIFigure);
            app.PuertosListBox.Items = {};
            app.PuertosListBox.Value = {};
            app.EstablecerComunicacinconTIVALabel = uilabel(app.UIFigure);
            app.EstablecerComunicacinconTIVALabel.Text = 'Establecer Comunicación con TIVA';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.IIRKnobLabel = uilabel(app.UIFigure);
            app.IIRKnobLabel.Text = 'IIR';
            app.IIRKnob = uiknob(app.UIFigure, 'discrete');
            app.IIRKnob.Items = {'None', 'Butterworth', 'Chebyshev tipo 1', 'Chebyshev tipo 2', 'Elíptico'};
            app.IIRKnob.ValueChangedFcn = createCallbackFcn(app, @IIRKnobValueChanged, true);
            app.IIRKnob.Value = 'None';
            app.Switch_3 = uiswitch(app.UIFigure, 'slider');
            app.Switch_3.Items = {'IIR', 'FIR'};
            app.Switch_3.Value = 'IIR';
            app.FIRKnobLabel = uilabel(app.UIFigure);
            app.FIRKnobLabel.Text = 'FIR';
            app.FIRKnob = uiknob(app.UIFigure, 'discrete');
            app.FIRKnob.Items = {'None', 'Equiripple', 'Least-squares', 'Constr. Least-Squares', 'Generalizes Equiripple'};
            app.FIRKnob.ValueChangedFcn = createCallbackFcn(app, @FIRKnobValueChanged, true);
            app.FIRKnob.Value = 'None';
            app.nEditFieldLabel = uilabel(app.UIFigure);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.UIFigure, 'text');
            app.RpEditFieldLabel = uilabel(app.UIFigure);
            app.RpEditFieldLabel.Text = 'Rp';
            app.RpEditField = uieditfield(app.UIFigure, 'text');
            app.Fs1EditFieldLabel = uilabel(app.UIFigure);
            app.Fs1EditFieldLabel.Text = 'Fs1';
            app.Fs1EditField = uieditfield(app.UIFigure, 'text');
            app.RsEditField_2Label = uilabel(app.UIFigure);
            app.RsEditField_2Label.Text = 'Rs';
            app.RsEditField_2 = uieditfield(app.UIFigure, 'text');
            app.TipodefiltroIIRListBoxLabel = uilabel(app.UIFigure);
            app.TipodefiltroIIRListBoxLabel.Text = 'Tipo de filtro (IIR)';
            app.TipodefiltroIIRListBox = uilistbox(app.UIFigure);
            app.TipodefiltroIIRListBox.Items = {'low', 'high', 'bandpass', 'stop'};
            app.TipodefiltroIIRListBox.Value = 'low';
            app.Fs2EditFieldLabel = uilabel(app.UIFigure);
            app.Fs2EditFieldLabel.Text = 'Fs2';
            app.Fs2EditField = uieditfield(app.UIFigure, 'text');
            app.TipodefiltroFIRListBoxLabel = uilabel(app.UIFigure);
            app.TipodefiltroFIRListBoxLabel.Text = 'Tipo de filtro (FIR)';
            app.TipodefiltroFIRListBox = uilistbox(app.UIFigure);
            app.TipodefiltroFIRListBox.Items = {'None', 'hilbert', 'differentiator'};
            app.TipodefiltroFIRListBox.Value = 'None';
            app.fEditFieldLabel = uilabel(app.UIFigure);
            app.fEditFieldLabel.Text = 'f';
            app.fEditField = uieditfield(app.UIFigure, 'text');
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'text');
            app.wEditFieldLabel = uilabel(app.UIFigure);
            app.wEditFieldLabel.Text = 'w';
            app.wEditField = uieditfield(app.UIFigure, 'text');
            app.f1EditFieldLabel = uilabel(app.UIFigure);
            app.f1EditFieldLabel.Text = 'f1';
            app.f1EditField = uieditfield(app.UIFigure, 'text');
            app.a1EditFieldLabel = uilabel(app.UIFigure);
            app.a1EditFieldLabel.Text = 'a1';
            app.a1EditField = uieditfield(app.UIFigure, 'text');
            app.f2EditFieldLabel = uilabel(app.UIFigure);
            app.f2EditFieldLabel.Text = 'f2';
            app.f2EditField = uieditfield(app.UIFigure, 'text');
            app.a3EditFieldLabel = uilabel(app.UIFigure);
            app.a3EditFieldLabel.Text = 'a3';
            app.a3EditField = uieditfield(app.UIFigure, 'text');
            app.f4EditFieldLabel = uilabel(app.UIFigure);
            app.f4EditFieldLabel.Text = 'f4';
            app.f4EditField = uieditfield(app.UIFigure, 'text');
            app.a4EditFieldLabel = uilabel(app.UIFigure);
            app.a4EditFieldLabel.Text = 'a4';
            app.a4EditField = uieditfield(app.UIFigure, 'text');
            app.CoeficientesparaTivaCLabel = uilabel(app.UIFigure);
            app.CoeficientesparaTivaCLabel.Text = 'Coeficientes para Tiva C';
            app.a0EditFieldLabel = uilabel(app.UIFigure);
            app.a0EditFieldLabel.Text = 'a0';
            app.a0EditField = uieditfield(app.UIFigure, 'text');
            app.a1EditField_2Label = uilabel(app.UIFigure);
            app.a1EditField_2Label.Text = 'a1';
            app.a1EditField_2 = uieditfield(app.UIFigure, 'text');
            app.a2EditFieldLabel = uilabel(app.UIFigure);
            app.a2EditFieldLabel.Text = 'a2';
            app.a2EditField = uieditfield(app.UIFigure, 'text');
            app.a3EditField_2Label = uilabel(app.UIFigure);
            app.a3EditField_2Label.Text = 'a3';
            app.a3EditField_2 = uieditfield(app.UIFigure, 'text');
            app.a4EditField_2Label = uilabel(app.UIFigure);
            app.a4EditField_2Label.Text = 'a4';
            app.a4EditField_2 = uieditfield(app.UIFigure, 'text');
            app.a5EditFieldLabel = uilabel(app.UIFigure);
            app.a5EditFieldLabel.Text = 'a5';
            app.a5EditField = uieditfield(app.UIFigure, 'text');
            app.aListBoxLabel = uilabel(app.UIFigure);
            app.aListBoxLabel.Text = 'a';
            app.aListBox = uilistbox(app.UIFigure);
            app.aListBox.Items = {};
            app.aListBox.Value = {};
            app.CoeficientesactualesLabel = uilabel(app.UIFigure);
            app.CoeficientesactualesLabel.Text = 'Coeficientes actuales';
            app.bListBoxLabel = uilabel(app.UIFigure);
            app.bListBoxLabel.Text = 'b';
            app.bListBox = uilistbox(app.UIFigure);
            app.bListBox.Items = {};
            app.bListBox.Value = {};
            app.b0EditFieldLabel = uilabel(app.UIFigure);
            app.b0EditFieldLabel.Text = 'b0';
            app.b0EditField = uieditfield(app.UIFigure, 'text');
            app.b1EditFieldLabel = uilabel(app.UIFigure);
            app.b1EditFieldLabel.Text = 'b1';
            app.b1EditField = uieditfield(app.UIFigure, 'text');
            app.b2EditFieldLabel = uilabel(app.UIFigure);
            app.b2EditFieldLabel.Text = 'b2';
            app.b2EditField = uieditfield(app.UIFigure, 'text');
            app.b3EditFieldLabel = uilabel(app.UIFigure);
            app.b3EditFieldLabel.Text = 'b3';
            app.b3EditField = uieditfield(app.UIFigure, 'text');
            app.b4EditFieldLabel = uilabel(app.UIFigure);
            app.b4EditFieldLabel.Text = 'b4';
            app.b4EditField = uieditfield(app.UIFigure, 'text');
            app.b5EditFieldLabel = uilabel(app.UIFigure);
            app.b5EditFieldLabel.Text = 'b5';
            app.b5EditField = uieditfield(app.UIFigure, 'text');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.FsLabel = uilabel(app.UIFigure);
            app.FsLabel.Text = 'Fs';
            app.GuardarButton_2 = uibutton(app.UIFigure, 'push');
            app.GuardarButton_2.ButtonPushedFcn = createCallbackFcn(app, @GuardarButton_2Pushed, true);
            app.GuardarButton_2.Text = 'Guardar ';
            app.FrecuEditField = uieditfield(app.UIFigure, 'text');
            app.AplicarFrecButton = uibutton(app.UIFigure, 'push');
            app.AplicarFrecButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarFrecButtonPushed, true);
            app.AplicarFrecButton.Text = 'Aplicar Frec.';
            app.Switch_4 = uiswitch(app.UIFigure, 'slider');
            app.Switch_4.Items = {'Muestra', 'Continuo'};
            app.Switch_4.Value = 'Muestra';
            app.ReproducirSonidoButton = uibutton(app.UIFigure, 'push');
            app.ReproducirSonidoButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirSonidoButtonPushed2, true);
            app.ReproducirSonidoButton.Text = 'Reproducir Sonido';
            app.ExportarSonidoButton = uibutton(app.UIFigure, 'push');
            app.ExportarSonidoButton.ButtonPushedFcn = createCallbackFcn(app, @ExportarSonidoButtonPushed, true);
            app.ExportarSonidoButton.Text = 'Exportar Sonido';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end