classdef V2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        EstablecerComunicacinTab      matlab.ui.container.Tab
        Label_8                       matlab.ui.control.Label
        BaudRateButtonGroup           matlab.ui.container.ButtonGroup
        Button_2                      matlab.ui.control.RadioButton
        Button                        matlab.ui.control.RadioButton
        PuertosDisponiblesListBox     matlab.ui.control.ListBox
        PuertosDisponiblesLabel       matlab.ui.control.Label
        ConectarButton                matlab.ui.control.Button
        ConectarESP32Label            matlab.ui.control.Label
        PuertosButton                 matlab.ui.control.Button
        LecturasTab                   matlab.ui.container.Tab
        Switch                        matlab.ui.control.RockerSwitch
        GuardarDatosButton            matlab.ui.control.Button
        SwitchLecturas                matlab.ui.control.ToggleSwitch
        LecturasSwitchLabel           matlab.ui.control.Label
        PedalLabel                    matlab.ui.control.Label
        CombustibleLabel              matlab.ui.control.Label
        LedLecturas                   matlab.ui.control.Lamp
        LedEstado                     matlab.ui.control.Lamp
        Lamp_2Label                   matlab.ui.control.Label
        EstadodelanaveLabel           matlab.ui.control.Label
        VelocidadLabel                matlab.ui.control.Label
        ValorVelocidad                matlab.ui.control.Label
        Velocidad                     matlab.ui.control.Gauge
        GaugeLabel_2                  matlab.ui.control.Label
        Label_7                       matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Gauge                         matlab.ui.control.LinearGauge
        Gauge_2                       matlab.ui.control.LinearGauge
        Temperatura4Label             matlab.ui.control.Label
        Temperatura3Label             matlab.ui.control.Label
        Temperatura2Label             matlab.ui.control.Label
        Temperatura1Label             matlab.ui.control.Label
        Temperatura4                  matlab.ui.control.Gauge
        Temperatura3                  matlab.ui.control.Gauge
        Temperatura2                  matlab.ui.control.Gauge
        Temperatura1                  matlab.ui.control.Gauge
        Label_3                       matlab.ui.control.Label
        YAW                           matlab.ui.control.Label
        PITCH                         matlab.ui.control.Label
        ROLL                          matlab.ui.control.Label
        RollPitchYawLabel             matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        ArtificialHorizon             Aero.ui.control.ArtificialHorizon
        RPMIndicator                  Aero.ui.control.RPMIndicator
        Image                         matlab.ui.control.Image
        UIAxes                        matlab.ui.control.UIAxes
        ConfiguracinTab               matlab.ui.container.Tab
        Image2                        matlab.ui.control.Image
        GuardarenEEPROMButton         matlab.ui.control.Button
        ValoresactualesButton         matlab.ui.control.Button
        ConfigurarButton              matlab.ui.control.Button
        LimpiarCamposButton           matlab.ui.control.Button
        NmerodepinesEditField         matlab.ui.control.NumericEditField
        NmerodepinesEditFieldLabel    matlab.ui.control.Label
        MuestreoEditField             matlab.ui.control.NumericEditField
        MuestreoEditFieldLabel        matlab.ui.control.Label
        CoversinEditField             matlab.ui.control.NumericEditField
        CoversinEditFieldLabel        matlab.ui.control.Label
        DimetrodeRuedaSenEditField    matlab.ui.control.NumericEditField
        DimetrodeRuedaSenEditFieldLabel  matlab.ui.control.Label
        DametrodeRuedaEditField       matlab.ui.control.NumericEditField
        DametrodeRuedaEditFieldLabel  matlab.ui.control.Label
        DatosaConfigurarLabel         matlab.ui.control.Label
    end


    properties (Access = public)
        baudrate;
        Fs;
        ports;
        serialdata;
        comconectado;
        RPM;
        VEL;
        roll;
        pitch;
        yaw;
        arreglodedatos;
        datosactuales;
        posiciones;

        simdata % Saved flight data [time X Y Z phi theta psi]
        animObj % Aero.Animation object
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.baudrate;
            app.Fs;
            app.ports;
            app.serialdata;
            app.comconectado = 0;
            app.RPM;
            app.VEL;
            app.roll;
            app.pitch;
            app.yaw;
            app.arreglodedatos;
            app.datosactuales;
            app.posiciones;


            % Load saved flight status data
            savedData = load(fullfile(matlabroot, 'toolbox', 'aero', 'astdemos', 'simdata.mat'), 'simdata');
            yaw = savedData.simdata(:,7);
            yaw(yaw<0) = yaw(yaw<0)+2*pi; % unwrap yaw angles
            savedData.simdata(:,7) = yaw;
            app.simdata = savedData.simdata;

            % Create animation object to visualize aircraft flight dynamics corresponding with saved data over time
            app.animObj = Aero.Animation;
            app.animObj.createBody('pa24-250_orange.ac','Ac3d'); % Piper PA-24 Comanche geometry
            app.animObj.Bodies{1}.TimeseriesSourceType = 'Array6DoF'; % [time X Y Z phi theta psi]
            app.animObj.Bodies{1}.TimeSeriesSource = app.simdata;
            app.animObj.Camera.PositionFcn = @staticCameraPosition;
            app.animObj.Figure.Position = [app.UIFigure.Position(1)+625 app.UIFigure.Position(2) app.UIFigure.Position(3) app.UIFigure.Position(4)];
            app.animObj.updateBodies(app.simdata(1,1)); % Initialize animation window at t=0
            app.animObj.updateCamera(app.simdata(1,1));
            app.animObj.show();
        end

        % Button pushed function: PuertosButton
        function PuertosButtonPushed(app, event)
            puertos = serialportlist; % Se obtienen los puertos disponibles, se pueden seleccionar
            app.PuertosDisponiblesListBox.Items = puertos;
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            s = 0;
            BaudRate = 0;

            % Establecemos BaudRate según el que esté seleccionado
            if app.Button.Value == 1
                BaudRate = 9600;
            elseif app.Button_2.Value == 1
                BaudRate = 115200;
            end


            if app.comconectado == 1 % Si ya estaba conectado, desconecta para volver a conectar
                app.serialdata = 0;
                s = 0;
            end

            % Establecemos conexión y guardamos datos
            s = serialport(app.PuertosDisponiblesListBox.Value,BaudRate);
            app.serialdata = s;
            s %% quitar luego de pruebas
            app.comconectado = 1;
        end

        % Button pushed function: GuardarDatosButton
        function GuardarDatosButtonPushed(app, event)
            datos = app.arreglodedatos;

            [file,path] = uiputfile('*.mat');
            if isequal(file,0) || isequal(path,0)
                disp('User clicked Cancel.')
            else
                disp(['User selected ',fullfile(path,file),...
                    ' and then clicked Save.'])
            end


            newfilename = fullfile(path, file);
            save(newfilename, 'datos')
        end

        % Value changed function: SwitchLecturas
        function SwitchLecturasValueChanged(app, event)
            value = app.SwitchLecturas.Value;


            contador = 1;
            recibido = zeros(1,15);
            dato = 0;
            comenzarrecibir = 0;
            historial = 1;
            tiempo = 0;

            if value == "Off"
                app.LedLecturas.Color = [1.00,0.00,0.00];
            elseif value == "On"
                app.LedLecturas.Color = [ 0.00,1.00,0.00];
                app.arreglodedatos = 0;


                while value == "On" %% recibimos datos, lo descomponemos y lo almacenamos

                    dato = readline(app.serialdata);

                    if dato == 'A' % Dato que indica inicio de comunicación
                        %recibido = 0;
                        comenzarrecibir = 1;
                        dato = readline(app.serialdata);
                        contador = 1;

                    elseif dato == 'B' %Dato que indica finalización de comunicación

                        comenzarrecibir = 0; % Se limpia la bandera de la funcion que agrupa los datos

                        %app.arreglodedatos(,historial) = recibido;
                        app.datosactuales = recibido;

                        %Desplegar angulos
                        app.ArtificialHorizon.Pitch = recibido(1,3)*0.33; % Se multiplica porque el pitch solo +-30, entonce solo será de referencia, el real se desplegara en label
                        app.ArtificialHorizon.Roll = recibido(1,2);
                        app.ROLL.Text = string(app.ArtificialHorizon.Roll);
                        app.PITCH.Text = string(app.ArtificialHorizon.Pitch);
                        app.YAW.Text = string(recibido(1,4));


                        %Desplegar velocidad
                        app.Velocidad.Value = recibido(1,1)*3.6; %El valor viene en m/s y 3.6 para que sea Km/h
                        app.ValorVelocidad.Text = string(app.Velocidad.Value);

                        %Graficar trayectoria
                        x(historial) = recibido(1,13);
                        y(historial) = recibido(1,14);
                        z(historial) = recibido(1,15);

                        
                        % Saved flight data [time X Y Z phi theta psi]
                        pos(historial,1) = tiempo;
                        pos(historial,2) = recibido(1,13);
                        pos(historial,3) = recibido(1,14);
                        pos(historial,4) = recibido(1,15);
                        pos(historial,5) = recibido(1,3);
                        pos(historial,6) = recibido(1,2);
                        pos(historial,7) = recibido(1,4);
                        

                        if app.Switch.Value == "2D"

                            plot(app.UIAxes,x,y);
                            %
                            %                             if abs(max(x)) > 0
                            %                                 figure(1);
                            %                                 xmin = min(x);
                            %                                 xmax = max(x);
                            %                                 ymin = min(y);
                            %                                 ymax = max(y);
                            %
                            %                                 tp = theaterPlot('XLim',[xmin,xmax],'YLim',[ymin,ymax]);
                            %                                 tPlotter = trackPlotter(tp,'DisplayName','Tracks','HistoryDepth',length(x));
                            %
                            %
                            %                                 plotTrack(app.UIAxes,tPlotter, pos, pos);
                            %                             end

                            app.animObj.updateBodies(pos(historial,1));
                            app.animObj.updateCamera(pos(historial,1));

                        elseif app.Switch.Value == "3D"

                            plot3(app.UIAxes,x,y,z);
                        end

                        %Deplegar valores de pedal

                        %Desplegar valores de Gasolina o combustible

                        %Desplegar RPM

                        %Desplegar temperaturas

                        %Se almacenan datos en variable global para luego
                        %generar el .mat
                        for i= 1 :length(recibido)
                            app.arreglodedatos(historial,i) = recibido(i);

                        end


                        % Contdor para que se guarden todos los datos que
                        % se reciben
                        historial = historial + 1; % contador
                        tiempo = tiempo + 0.1;


                    end

                    % Funcion que guarda datos que recibe vía UART, los
                    % agrupa para luego convertirlos.
                    if comenzarrecibir == 1
                        recibido(1,contador) = str2double(dato);
                        contador = contador + 1;


                    end

                end


            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            delete(app.animObj);
            delete(app);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EstablecerComunicacinTab = uitab(app.TabGroup);
            app.EstablecerComunicacinTab.Title = 'Establecer Comunicación';
            app.PuertosButton = uibutton(app.EstablecerComunicacinTab, 'push');
            app.PuertosButton.ButtonPushedFcn = createCallbackFcn(app, @PuertosButtonPushed, true);
            app.PuertosButton.Text = 'Puertos';
            app.ConectarESP32Label = uilabel(app.EstablecerComunicacinTab);
            app.ConectarESP32Label.Text = 'Conectar ESP32';
            app.ConectarButton = uibutton(app.EstablecerComunicacinTab, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.PuertosDisponiblesLabel = uilabel(app.EstablecerComunicacinTab);
            app.PuertosDisponiblesLabel.Text = {'Puertos '; 'Disponibles'};
            app.PuertosDisponiblesListBox = uilistbox(app.EstablecerComunicacinTab);
            app.PuertosDisponiblesListBox.Items = {};
            app.PuertosDisponiblesListBox.Value = {};
            app.BaudRateButtonGroup = uibuttongroup(app.EstablecerComunicacinTab);
            app.BaudRateButtonGroup.Title = 'BaudRate';
            app.Button = uiradiobutton(app.BaudRateButtonGroup);
            app.Button.Text = '9600';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.BaudRateButtonGroup);
            app.Button_2.Text = '115200';
            app.Label_8 = uilabel(app.EstablecerComunicacinTab);
            app.Label_8.Text = '';
            app.LecturasTab = uitab(app.TabGroup);
            app.LecturasTab.Title = 'Lecturas';
            app.UIAxes = uiaxes(app.LecturasTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.LecturasTab);
            app.Image.ImageSource = 'Logo.jpeg';
            app.RPMIndicator = uiaerorpm(app.LecturasTab);
            app.ArtificialHorizon = uiaerohorizon(app.LecturasTab);
            app.Label = uilabel(app.LecturasTab);
            app.Label.Text = '';
            app.RollPitchYawLabel = uilabel(app.LecturasTab);
            app.RollPitchYawLabel.Text = {'Roll:'; 'Pitch:'; 'Yaw:'};
            app.ROLL = uilabel(app.LecturasTab);
            app.PITCH = uilabel(app.LecturasTab);
            app.PITCH.Text = 'Label2';
            app.YAW = uilabel(app.LecturasTab);
            app.YAW.Text = 'Label3';
            app.Label_3 = uilabel(app.LecturasTab);
            app.Label_3.Text = '';
            app.Temperatura1 = uigauge(app.LecturasTab, 'circular');
            app.Temperatura1.MajorTicks = [0 100];
            app.Temperatura1.MajorTickLabels = {'C', 'H'};
            app.Temperatura2 = uigauge(app.LecturasTab, 'circular');
            app.Temperatura2.MajorTicks = [0 100];
            app.Temperatura2.MajorTickLabels = {'C', 'H'};
            app.Temperatura3 = uigauge(app.LecturasTab, 'circular');
            app.Temperatura3.MajorTicks = [0 100];
            app.Temperatura3.MajorTickLabels = {'C', 'H'};
            app.Temperatura4 = uigauge(app.LecturasTab, 'circular');
            app.Temperatura4.MajorTicks = [0 100];
            app.Temperatura4.MajorTickLabels = {'C', 'H'};
            app.Temperatura1Label = uilabel(app.LecturasTab);
            app.Temperatura1Label.Text = 'Temperatura 1';
            app.Temperatura2Label = uilabel(app.LecturasTab);
            app.Temperatura2Label.Text = 'Temperatura 2';
            app.Temperatura3Label = uilabel(app.LecturasTab);
            app.Temperatura3Label.Text = 'Temperatura 3';
            app.Temperatura4Label = uilabel(app.LecturasTab);
            app.Temperatura4Label.Text = 'Temperatura 4';
            app.Gauge_2 = uigauge(app.LecturasTab, 'linear');
            app.Gauge = uigauge(app.LecturasTab, 'linear');
            app.Label_4 = uilabel(app.LecturasTab);
            app.Label_4.Text = '96';
            app.Label_5 = uilabel(app.LecturasTab);
            app.Label_5.Text = '96';
            app.Label_6 = uilabel(app.LecturasTab);
            app.Label_6.Text = '96';
            app.Label_7 = uilabel(app.LecturasTab);
            app.Label_7.Text = '96';
            app.GaugeLabel_2 = uilabel(app.LecturasTab);
            app.GaugeLabel_2.Text = 'Gauge';
            app.Velocidad = uigauge(app.LecturasTab, 'circular');
            app.ValorVelocidad = uilabel(app.LecturasTab);
            app.ValorVelocidad.Text = '';
            app.VelocidadLabel = uilabel(app.LecturasTab);
            app.VelocidadLabel.Text = 'Velocidad';
            app.EstadodelanaveLabel = uilabel(app.LecturasTab);
            app.EstadodelanaveLabel.Text = 'Estado de la nave';
            app.Lamp_2Label = uilabel(app.LecturasTab);
            app.Lamp_2Label.Text = '';
            app.LedEstado = uilamp(app.LecturasTab);
            app.LedLecturas = uilamp(app.LecturasTab);
            app.CombustibleLabel = uilabel(app.LecturasTab);
            app.CombustibleLabel.Text = 'Combustible';
            app.PedalLabel = uilabel(app.LecturasTab);
            app.PedalLabel.Text = 'Pedal';
            app.LecturasSwitchLabel = uilabel(app.LecturasTab);
            app.LecturasSwitchLabel.Text = 'Lecturas';
            app.SwitchLecturas = uiswitch(app.LecturasTab, 'toggle');
            app.SwitchLecturas.ValueChangedFcn = createCallbackFcn(app, @SwitchLecturasValueChanged, true);
            app.GuardarDatosButton = uibutton(app.LecturasTab, 'push');
            app.GuardarDatosButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarDatosButtonPushed, true);
            app.GuardarDatosButton.Text = 'Guardar Datos';
            app.Switch = uiswitch(app.LecturasTab, 'rocker');
            app.Switch.Items = {'2D', '3D'};
            app.Switch.Value = '2D';
            app.ConfiguracinTab = uitab(app.TabGroup);
            app.ConfiguracinTab.Title = 'Configuración';
            app.DatosaConfigurarLabel = uilabel(app.ConfiguracinTab);
            app.DatosaConfigurarLabel.Text = 'Datos a Configurar';
            app.DametrodeRuedaEditFieldLabel = uilabel(app.ConfiguracinTab);
            app.DametrodeRuedaEditFieldLabel.Text = 'Díametro de Rueda';
            app.DametrodeRuedaEditField = uieditfield(app.ConfiguracinTab, 'numeric');
            app.DimetrodeRuedaSenEditFieldLabel = uilabel(app.ConfiguracinTab);
            app.DimetrodeRuedaSenEditFieldLabel.Text = 'Diámetro de Rueda Sen.';
            app.DimetrodeRuedaSenEditField = uieditfield(app.ConfiguracinTab, 'numeric');
            app.CoversinEditFieldLabel = uilabel(app.ConfiguracinTab);
            app.CoversinEditFieldLabel.Text = 'Coversión';
            app.CoversinEditField = uieditfield(app.ConfiguracinTab, 'numeric');
            app.MuestreoEditFieldLabel = uilabel(app.ConfiguracinTab);
            app.MuestreoEditFieldLabel.Text = 'Muestreo';
            app.MuestreoEditField = uieditfield(app.ConfiguracinTab, 'numeric');
            app.NmerodepinesEditFieldLabel = uilabel(app.ConfiguracinTab);
            app.NmerodepinesEditFieldLabel.Text = 'Número de pines';
            app.NmerodepinesEditField = uieditfield(app.ConfiguracinTab, 'numeric');
            app.LimpiarCamposButton = uibutton(app.ConfiguracinTab, 'push');
            app.LimpiarCamposButton.Text = 'Limpiar Campos';
            app.ConfigurarButton = uibutton(app.ConfiguracinTab, 'push');
            app.ConfigurarButton.Text = 'Configurar';
            app.ValoresactualesButton = uibutton(app.ConfiguracinTab, 'push');
            app.ValoresactualesButton.Text = 'Valores actuales';
            app.GuardarenEEPROMButton = uibutton(app.ConfiguracinTab, 'push');
            app.GuardarenEEPROMButton.Text = 'Guardar en EEPROM';
            app.Image2 = uiimage(app.ConfiguracinTab);
            app.Image2.ImageSource = 'Reparar-removebg-preview.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = V2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end